classdef GraficasCalidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        SimulacinRobotButton          matlab.ui.control.Button
        NiveldedificultadButtonGroup  matlab.ui.container.ButtonGroup
        Nivel4                        matlab.ui.control.RadioButton
        Nivel3                        matlab.ui.control.RadioButton
        Nivel2                        matlab.ui.control.RadioButton
        Nivel1                        matlab.ui.control.RadioButton
        PorcentajedefalloLabel        matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        IniciarmovimientoButton       matlab.ui.control.Button
        ResultadosButton              matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        FHombro                       matlab.ui.control.UIAxes
        AHombro                       matlab.ui.control.UIAxes
        SCodo                         matlab.ui.control.UIAxes
        FCodo                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        rango = 9; % Description
        pintar = 0; % Description
        contador = 0;
    end

    methods (Access = private)

        function readData(app,src,~)
            data = readline(src);
            data=split(data);
            data =str2double(data);

            src.UserData.Data(size(src.UserData.Data,1)+1,:)= data;

            src.UserData.Count = src.UserData.Count + 1;

            if src.UserData.Count > 100001
                configureCallback(src,"off");
                plot(app.scr.UserData.Data(2:end));
                %     plot(app.SCodo.scr.UserData.Data(2:end));
                %     plot(app.AHombro.scr.UserData.Data(2:end));
                %     plot(app.FHombro.scr.UserData.Data(2:end));
                %     plot(app.AMuneca.scr.UserData.Data(2:end));
            end
        end
        function [mSUP, mINF] = RangosCalidadApp ( app, yIdeal, ejex, rango)

            %Grafica ideal
            %y = [10,14,19,26,31,33,30,26,21,16,10];

            x = 0:0.1:1;
            %Rangos
            rSUP = yIdeal + rango;
            rINF= yIdeal - rango;

            xR = 0:0.001:1;

            %makima rango SUP
            ppSUP= makima(x*ejex,rSUP);
            mSUP= ppval(ppSUP, xR*ejex);

            %makima rango INF
            ppINF= makima(x*ejex,rINF);
            mINF= ppval(ppINF, xR*ejex);


            % plot(xR*duration,mSUP, 'r'), hold on, plot(xR*duration,mINF, 'r')
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)

            app.pintar = 1;
            %tiempo de duracion del movimiento
            maxim= 4;
            minim= 1;
            duration = minim+(maxim-minim)*rand(1);


            % value = app.TiempoTotal.Value - duration;
            [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'cl');
            [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'cl');
            [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'cl');
            [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'cl');
            [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'cl');
            [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1,duration,'cl');

            reset(app.SCodo),reset(app.AHombro),reset(app.FHombro),reset(app.FCodo)

            % ejex=3000;

            %HA


            muestras = 3900;
            x1M = 1:5 :muestras;
           

            %Pintamos los rangos


            puerto = serialport("COM9",115200);
            configureTerminator(puerto,"LF",4);
            puerto.UserData = struct('Data',[],'Count',1);
            readline(puerto)
            write(puerto, 's','string')
            for i = 1:40
                readline(puerto);

            end
            flush(puerto);
            puerto.UserData.Data=[];
            i = 1;


            while(app.pintar == 1)
                readData(app,puerto);

                x1 = puerto.UserData.Data(:,1); %supinacion sensor 1 codo
                plot(x1M(1:i),x1(1:i),'r');
                drawnow;

                %  x = puerto.UserData.Data(:,1);
                %      plot(app.FMuneca,x2(1:i),x(1:i),'r');
                % drawnow;
                %
                % x = puerto.UserData.Data(:,1);
                %     plot(app.FCodo,x3(1:i),x(1:i),'r');
                % drawnow;
                %
                y1 = puerto.UserData.Data(:,2); %flexion sensor 1 codo
                %  plot(app.AMuneca,x4(1:i),y(1:i),'g');
                % drawnow;
                %
                % y = puerto.UserData.Data(:,2);
                %  plot(app.AHombro,x5(1:i),y(1:i),'g');
                % drawnow;
                %
                x2 = puerto.UserData.Data(:,3);
                %  plot(app.SCodo,x6(1:i),z(1:i),'b');
                % drawnow;

                 y2 = puerto.UserData.Data(:,4);
                i=i+1;
            end

            comprobar = 1;
            if (comprobar ~= 0)
                app.contador = app.contador +1;
            end
            if (app.pintar == 0)
                filename = 'PruebasCalidad.xlsx';

                xlswrite(filename,x1,app.contador,'A')
                xlswrite(filename,y1,app.contador,'B')
                xlswrite(filename,x2,app.contador,'C')
                xlswrite(filename,y2,app.contador,'D')


                x1r =length(x1);
                y1r = length(y1);
                x2r =length(x2);
                y2r = length(y2);
                

                [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, x2r,'cl');
                [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, y2r,'cl');
                [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, y1r,'cl');
                [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, x1r,'cl');
                %       [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'cl');
                %       [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1,duration,'cl');


                [mSUP, mINF]=  RangosCalidadApp ( app, [10,14,19,26,31,33,30,26,21,16,10], y2r, app.rango);
                rHAS = mSUP;
                rHAI = mINF;

                [mSUP, mINF]=  RangosCalidadApp ( app, [67,70,89,108,115,120,115,107,83,72,67], y1r, app.rango);
                rCFS = mSUP;
                rCFI = mINF;
                %HF
                [mSUP, mINF]=  RangosCalidadApp ( app, [40,42,45,50,56,60,56,50,45,42,40], x2r, app.rango);
                rHFS = mSUP;
                rHFI = mINF;
              
                [mSUP, mINF]=  RangosCalidadApp ( app, [25,28,35,42,52,51,54,25,17,10,25], x1r, app.rango);
                rCSS = mSUP;
                rCSI = mINF;


                %Pintar Rangos
                plot(app.FHombro,xHFF,rHFS,'--r'), hold (app.FHombro),
                plot(app.FHombro,xHFF,rHFI,'--r'),
                plot(app.AHombro,xHAF,rHAS,'--r'), hold (app.AHombro),
                plot(app.AHombro,xHAF,rHAI,'--r'),
                plot(app.SCodo,xCSF,rCSS,'--r'), hold (app.SCodo),
                plot(app.SCodo,xCSF,rCSI,'--r')
               
                plot(app.FCodo,xCFF,rCFS,'--r'), hold (app.FCodo),
                plot(app.FCodo,xCFF,rCFI,'--r')

                [num,txt,raw] =  xlsread(filename,app.contador)
                plot(app.FHombro,num(:,3)), hold on;
                plot(app.AHombro,num(:,4)), hold on;
                plot(app.FCodo,num(:,2)), hold on;%SEGUNDO SENSOR
                plot(app.SCodo,num(:,1)), hold on; %SEGUNDO SENSOR


            end

            %x = puerto.UserData.Data(:,1);
            %y = puerto.UserData.Data(:,2);
            %z = puerto.UserData.Data(:,3);

            %{
%plot(app.FCodo,x)
%hold on
plot(app.FMuneca,x)
hold on
%plot(app.FHombro,x)
hold on
plot(app.AMuneca,y,'r')
hold on
plot(app.AHombro,y,'r')
hold on
plot(app.SCodo,z,'g')
            %}

            %{
for i = 1:n
plot(app.FHombro,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.FMuneca,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.FCodo,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.AMuneca,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.AHombro,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.FHombro,x1(1:i),x(1:i),'-r');
drawnow;
end
                        %}

            maxsize = max(length(x1)); %SEGUNDO SENSOR
            i=1;

            %Comparamos el eje y con los rangos para ver que no se ha salido
            j = 1;
            fHF = 0;
            fHA = 0;
            fCF = 0;
            fCS = 0;
            fMF = 0;
            fMA = 0;

            %Fallo flexion primer sensor
           
            while j < x1r
              
                    if x2(j) < rHFS(j) %Comparamos con rango superior
                          fHF = fHF+0;
                     if x2(j) > rHFI(j) %Comparamos con rango inferior
                           fHF = fHF+0; %no se suma fallo  
                     else
                         fHF = fHF+1;
                     end
                    else
                        fHF = fHF+1;
                    end
%                     else
%                         fHF = fHF+1; %Se suma fallo
%                     end


            %Fallo abduccion primer sensor
 if y2(j) < rHAS(j) %Comparamos con rango superior
                         fHA = fHA+0;
                     if y2(j) > rHAI(j) %Comparamos con rango inferior
                            fHA = fHA+0; 
                     else
                         fHA = fHA+1; 
                     end
 else
     fHA = fHA+1; 
 end


%                     else
%                         fHA = fHA+1; %Se suma fallo
%                     end
                

           %Fallo flexion segundo sensor
if y1(j) < rCFS(j) %Comparamos con rango superior
                         fCF = fCF+0;
                     if y1(j) > rCFI(j) %Comparamos con rango inferior
                            fCF = fCF+0; %no se suma fallo
                     else
fCF = fCF+1;
                     end
else
fCF = fCF+1;
end


%                     else
%                         fCF = fCF+1; %Se suma fallo
%                     end
                
            %Fallo supinacion codo primer sensor
           
if x1(j) < rCSS(j) %Comparamos con rango superior
                         fCS = fCS+0;
                     if x1(j) > rCSI(j) %Comparamos con rango inferior
                            fCS = fCS+0; %no se suma fallo 
                     else
                         fCS = fCS+1;
                     end
else
    fCS = fCS+1;
end


%                     else
%                         fCS = fCS+1; %Se suma fallo
%                     end
                
                j=j+1;

            end

            fTotal = (fHA + fHF+ fCS + fCF) ;
            %+ fHA + fHF;


            ftotal100 = (fTotal *100) / (x1r*4) ; %CAMBIAR *6 CUANDO SE IMPLEMENTE MUÑECA
            value = 'El porcentaje de fallo es del ' + string(ftotal100) + ' %';
            set(app.PorcentajedefalloLabel, 'Text', value);


            fCS100 = (fCS * 100) / x1r;
            fCF100 = (fCF * 100) / x1r;
           fHA100 = (fHA * 100) / x1r;
           fHF100 = (fHF * 100) / x1r;


            %-----------------------------------------
            %Escribir en un excel


            ID = app.contador;
            LabelCalidad = {'Calidad'};
            LabelTitulos = {'Metrica','Movimiento','ID','Porcentaje de fallos total','Porcentaje de fallo', 'Rango','Maximo', 'Minimo'};
 
            xlswrite('DatosInformeCalidad2.xlsx',LabelTitulos,app.contador,'A1');
            xlswrite('DatosInformeCalidad2.xlsx',LabelCalidad,app.contador,'A2:A5');
            xlswrite('DatosInformeCalidad2.xlsx',ID,app.contador,'C2:C5');
            
            %MOVIMIENTOS 
            FlexHombro = {'Flexión de Hombro'};
            AbdHombro = {'Abducción de Hombro'};
            FlexCodo = {'Flexión de Hombro'};
            SupCodo = {'Supinación de Codo'};
            xlswrite('DatosInformeCalidad2.xlsx',FlexHombro,app.contador,'B2');
            xlswrite('DatosInformeCalidad2.xlsx',AbdHombro,app.contador,'B3');
            xlswrite('DatosInformeCalidad2.xlsx',FlexCodo,app.contador,'B4');
            xlswrite('DatosInformeCalidad2.xlsx',SupCodo,app.contador,'B5');


            %MAXIMOS
            maximoHF = max (x2);
            maximoHA = max(y2);
            maximoCS = max(x1); %Segundo sensor
            maximoCF = max(y1); %Segunado sensor

            xlswrite('DatosInformeCalidad2.xlsx',maximoHF,app.contador,'G2');
            xlswrite('DatosInformeCalidad2.xlsx',maximoHA,app.contador,'G3');
            xlswrite('DatosInformeCalidad2.xlsx',maximoCF,app.contador,'G4');
            xlswrite('DatosInformeCalidad2.xlsx',maximoCS,app.contador,'G5');


            %MINIMOS
            minimoHF = min(x2);
            minimoHA = min(y2);
            minimoCS = min(x1);
            minimoCF = min(y1);

            xlswrite('DatosInformeCalidad2.xlsx',minimoHF,app.contador,'H2');
            xlswrite('DatosInformeCalidad2.xlsx',minimoHA,app.contador,'H3');
            xlswrite('DatosInformeCalidad2.xlsx',minimoCS,app.contador,'H4');
            xlswrite('DatosInformeCalidad2.xlsx',minimoCF,app.contador,'H5');


            %RANGOS
            rangoHF = maximoHF - minimoHF;
            rangoHA = maximoHA - minimoHA;
            rangoCS = maximoCS - minimoCS;
            rangoCF = maximoCF - minimoCF;

            xlswrite('DatosInformeCalidad2.xlsx',rangoHF,app.contador,'F2');
            xlswrite('DatosInformeCalidad2.xlsx',rangoHA,app.contador,'F3');
            xlswrite('DatosInformeCalidad2.xlsx',rangoCS,app.contador,'F4');
            xlswrite('DatosInformeCalidad2.xlsx',rangoCF,app.contador,'F5');


            %FALLOS TOTAL 

             xlswrite('DatosInformeCalidad2.xlsx',ftotal100,app.contador,'D2:D5');
       
            %FALLO INDIVIDUAL


            xlswrite('DatosInformeCalidad2.xlsx',fHF100,app.contador,'E2');
            xlswrite('DatosInformeCalidad2.xlsx',fHA100,app.contador,'E3');
            xlswrite('DatosInformeCalidad2.xlsx',fCF100,app.contador,'E4');
            xlswrite('DatosInformeCalidad2.xlsx',fCS100,app.contador,'E5');
   

        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Calidad;
            closereq ();
        end

        % Selection changed function: NiveldedificultadButtonGroup
        function NiveldedificultadButtonGroupSelectionChanged(app, event)
            selectedButton = app.NiveldedificultadButtonGroup.SelectedObject;

            if selectedButton == app.Nivel1
                app.rango = 9;
            elseif selectedButton == app.Nivel2
                app.rango = 7;
            elseif selectedButton == app.Nivel3
                app.rango = 5;
            elseif selectedButton == app.Nivel4
                app.rango = 3;
            else
                app.rango=9;
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           close
           close
            
            app.pintar=0;

        end

        % Button pushed function: SimulacinRobotButton
        function SimulacinRobotButtonPushed(app, event)
            
      
 filename = 'PruebasCalidad.xlsx';
               
a = arduino('COM8');
sHombro =servo(a,'A0'); 
sCodo = servo(a,'A1');
datos = xlsread(filename,app.contador);
datosFlexionHombro = datos(:,3);
datosFlexionCodo = datos(:,2);


datosFlexionHombro = datosFlexionHombro*1/180;
datosFlexionCodo = datosFlexionCodo*1/180;


for i=1 : length(datosFlexionHombro)

        writePosition(sHombro,datosFlexionHombro(i,1))
        writePosition(sCodo,datosFlexionCodo(i,1))
       i=i+1;
       pause(0.3)
  
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.ResultadosButton = uibutton(app.GridLayout2, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.PorcentajedefalloLabel = uilabel(app.GridLayout);
            app.PorcentajedefalloLabel.Text = 'Porcentaje de fallo:';
            app.NiveldedificultadButtonGroup = uibuttongroup(app.GridLayout);
            app.NiveldedificultadButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NiveldedificultadButtonGroupSelectionChanged, true);
            app.NiveldedificultadButtonGroup.Title = 'Nivel de dificultad';
            app.Nivel1 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel1.Text = '1';
            app.Nivel1.Value = true;
            app.Nivel2 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel2.Text = '2';
            app.Nivel3 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel3.Text = '3';
            app.Nivel4 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel4.Text = '4';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SimulacinRobotButton = uibutton(app.GridLayout3, 'push');
            app.SimulacinRobotButton.ButtonPushedFcn = createCallbackFcn(app, @SimulacinRobotButtonPushed, true);
            app.SimulacinRobotButton.Text = {'Simulación Robot'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasCalidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end