classdef GraficasCantidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        TiempoTotal              matlab.ui.control.NumericEditField
        TiemposLabel             matlab.ui.control.Label
        GridLayout5              matlab.ui.container.GridLayout
        MovimientoLabel          matlab.ui.control.Label
        Contador                 matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        ResultadosButton         matlab.ui.control.Button
        GridLayout2              matlab.ui.container.GridLayout
        IniciarmovimientoButton  matlab.ui.control.Button
        SCodo                    matlab.ui.control.UIAxes
        FMuneca                  matlab.ui.control.UIAxes
        AMuneca                  matlab.ui.control.UIAxes
        FCodo                    matlab.ui.control.UIAxes
        AHombro                  matlab.ui.control.UIAxes
        FHombro                  matlab.ui.control.UIAxes
    end


    methods (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
           %tiempo de duracion del movimiento
            maxim= 4;
            minim= 1;
            duration = minim+(maxim-minim)*rand(1);
            
      [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration)
      [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration);  
      [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration)
      [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration)
      [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration)
      [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1, duration)
   

    maxsize=max([length(yHAF),length(yHFF),length(yMAF),length(yMFF),length(yCFF),length(yCSF)]);
    i=1;
    while i<maxsize
        if i<length(yHFF)
            plot(app.FHombro,xHFF(1:i),yHFF(1:i))
        end
        if i<length(yHAF)
            plot(app.AHombro,xHAF(1:i),yHAF(1:i))
        end
        if i<length(yCFF)
            plot(app.FCodo,xCFF(1:i),yCFF(1:i))
        end
        if i<length(yCSF)
            plot(app.SCodo,xCSF(1:i),yCSF(1:i))
        end
        if i<length(yMFF)
            plot(app.FMuneca,xMFF(1:i),yMFF(1:i))
        end
        if i<length(yMAF)
            plot(app.AMuneca,xMAF(1:i),yMAF(1:i))
        end
        i=i+9;
        drawnow
        pause(0.01)
    end
    

        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Tiempo;
            closereq ();
        end

        % Value changed function: TiempoTotal
        function TiempoTotalValueChanged(app, event)
            value = app.TiempoTotal.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.AMuneca = uiaxes(app.GridLayout);
            title(app.AMuneca, {'Abducción Muñeca'; ''})
            xlabel(app.AMuneca, 'X')
            ylabel(app.AMuneca, 'Y')
            zlabel(app.AMuneca, 'Z')
            app.FMuneca = uiaxes(app.GridLayout);
            title(app.FMuneca, 'Flexión Muñeca')
            xlabel(app.FMuneca, 'X')
            ylabel(app.FMuneca, 'Y')
            zlabel(app.FMuneca, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ResultadosButton = uibutton(app.GridLayout3, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.Contador = uieditfield(app.GridLayout5, 'numeric');
            app.MovimientoLabel = uilabel(app.GridLayout5);
            app.MovimientoLabel.Text = 'Movimiento:';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.TiemposLabel = uilabel(app.GridLayout6);
            app.TiemposLabel.Text = {'Tiempo (s):'; ''};
            app.TiempoTotal = uieditfield(app.GridLayout6, 'numeric');
            app.TiempoTotal.ValueChangedFcn = createCallbackFcn(app, @TiempoTotalValueChanged, true);
            app.TiempoTotal.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasCantidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end